classdef App_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Switch          matlab.ui.control.Switch
        SwitchLabel     matlab.ui.control.Label
        Vx0Slider       matlab.ui.control.Slider
        Vx0SliderLabel  matlab.ui.control.Label
        x0Slider        matlab.ui.control.Slider
        x0SliderLabel   matlab.ui.control.Label
        Label           matlab.ui.control.Label
        RunButton       matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: RunButton, x0Slider
        function RunButtonPushed(app, event)
        X=app.x0Slider.Value;
        Y=app.Vx0Slider.Value;
        val=app.Switch.Value;
        if strcmpi(val,'on')==1
            x0ind=1;
    for x0 =-10:10:10
        tic
        x0ind=x0ind+1;
        Vx0ind=1;
        for Vx0 =-10:10:10
            Mat(x0ind,Vx0ind)=MainFunksjon(x0,Vx0);
            Mat1(x0ind,Vx0ind)=MainFunksjonIkkePlot(x0,Vx0);
            Vx0ind=Vx0ind+1;
            toc;
        end
    end
    [x0,Vx0]=meshgrid(-10:10:10,-10:10:10);
    pcolor(x0,Vx0,Mat(x0ind,Vx0ind),'parent',app.UIAxes);
    colormap(jet)
        else
         pcolor([0 0],[0 0],[0 0;0 0],'parent',app.UIAxes);  
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.x0SliderLabel = uilabel(app.UIFigure);
            app.x0SliderLabel.Text = 'x0';
            app.x0Slider = uislider(app.UIFigure);
            app.x0Slider.Limits = [-10 10];
            app.x0Slider.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Vx0SliderLabel = uilabel(app.UIFigure);
            app.Vx0SliderLabel.Text = 'Vx0';
            app.Vx0Slider = uislider(app.UIFigure);
            app.Vx0Slider.Limits = [-30 30];
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end